classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        ImportImageMenu  matlab.ui.container.Menu
        BalanceButton    matlab.ui.control.Button
        UIImage          matlab.ui.control.Image
        UIAxesHistogram  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage   % 原始图像
        ProcessedImage  % 均衡化后的图像
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportImageMenu, Menu
        function ImportImageMenuSelected(app, event)
            % 打开文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.gif', 'Image Files (*.jpg, *.png, *.jpeg, *.bmp, *.gif)'});
            
            if file ~= 0  % 如果用户选择了文件
                imgPath = fullfile(path, file);
                img = imread(imgPath);
                % 将图像路径赋给 UIImage 组件的 ImageSource 属性
                app.UIImage.ImageSource = imgPath;  % 显示图片
                
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                app.OriginalImage = img;
                 % 清除之前的直方图
                cla(app.UIAxesHistogram);  % 清除 Axes 上之前的内容
                
                % 计算并显示灰度直方图
                histogram(app.UIAxesHistogram, img, 'BinEdges', 0:256);  % 在现有的 Axes 上绘制灰度直方图
                title(app.UIAxesHistogram, '灰度直方图');
                xlabel(app.UIAxesHistogram, '灰度值');
                ylabel(app.UIAxesHistogram, '像素数量');
                axis(app.UIAxesHistogram, 'tight');  % 紧缩坐标轴
            else
                disp('No image selected');
            end
        end

        % Button pushed function: BalanceButton
        function BalanceButtonPushed(app, event)
            if isempty(app.OriginalImage)
                % 如果没有导入图像，提示用户
                uialert(app.UIFigure, '请先导入图像！', '图像未加载');
                return;
            end
            
            % 对图像进行均衡化
            balancedImg = histeq(app.OriginalImage);
            app.ProcessedImage = balancedImg;
            
            if size(balancedImg, 3) == 1  % 如果是灰度图像
                balancedImgRGB = cat(3, balancedImg, balancedImg, balancedImg);  % 将灰度图像转换为 RGB 图像
            else
                balancedImgRGB = balancedImg;  % 如果已经是彩色图像，则不需要转换
            end
            
            
            % 显示均衡化后的图像
            app.UIImage.ImageSource = balancedImgRGB;  
            
            % 更新灰度直方图
            axes(app.UIAxesHistogram);  % 激活现有的 Axes 组件
            histogram(app.UIAxesHistogram, balancedImg, 'BinEdges', 0:256);  % 绘制均衡化后的灰度直方图
            title(app.UIAxesHistogram, '灰度直方图（均衡化后）');
            xlabel(app.UIAxesHistogram, '灰度值');
            ylabel(app.UIAxesHistogram, '像素数量');
            axis(app.UIAxesHistogram, 'tight');  % 紧缩坐标轴
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.Menu.Text = 'Menu';
            app.ImportImageMenu = uimenu(app.Menu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.ImportImageMenu.Text = 'Import Image';
            app.UIAxesHistogram = uiaxes(app.UIFigure);
            title(app.UIAxesHistogram, '灰度直方图')
            xlabel(app.UIAxesHistogram, '灰度值')
            ylabel(app.UIAxesHistogram, '像素数量')
            zlabel(app.UIAxesHistogram, 'Z')
            app.UIImage = uiimage(app.UIFigure);
            app.BalanceButton = uibutton(app.UIFigure, 'push');
            app.BalanceButton.ButtonPushedFcn = createCallbackFcn(app, @BalanceButtonPushed, true);
            app.BalanceButton.Text = '均衡化';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end